classdef app_VisAndQuant_ASLResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ViewDropDown         matlab.ui.control.DropDown
        ViewDropDownLabel    matlab.ui.control.Label
        SliceEditField       matlab.ui.control.NumericEditField
        SliceEditFieldLabel  matlab.ui.control.Label
        SlicesCheckBox       matlab.ui.control.CheckBox
        BETCheckBox          matlab.ui.control.CheckBox
        SavematButton        matlab.ui.control.Button
        QuantifyPanel        matlab.ui.container.Panel
        LVLabel              matlab.ui.control.Label
        VBLabel              matlab.ui.control.Label
        PCALabel             matlab.ui.control.Label
        MCALabel             matlab.ui.control.Label
        ACALabel             matlab.ui.control.Label
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
        LoadCBFMapsButton    matlab.ui.control.Button
        DropDownDay          matlab.ui.control.DropDown
        SliceSlider          matlab.ui.control.Slider
        SliceSliderLabel     matlab.ui.control.Label
        UIAxes3_11           matlab.ui.control.UIAxes
        UIAxes3_10           matlab.ui.control.UIAxes
        UIAxes3_9            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ASLData % The ASL data
        T1Data % The BET T1 data
        subFolders %Subfolders
        days %The name of the days
        currentData %The data in the big plot
        sizeX = 20 %Box width
        sizeY = 20 %Box height
        posX = 100 %Box x coord
        posY = 100 %Box y coord
        initsizeX = 10 %Box width
        initsizeY = 20 %Box height
        initposX = 100 %Box x coord
        initposY = 100 %Box y coord
        showInAllPlots %Show the rectangle in all plots
        mask %Mask computed from rectangle
        yLim = 80;
        noT1Bet = 1; %If doing Bet on T1 or not
        T1pathBet %Path to T1 data with BET
        T1path %Path to T1 data without BET
        yMinT1 %Min value in plot
        yMaxT1 %Max value in plot
        to40Slices %Flag if downsampling data
        dataFolder %The chosen folder in the uigetdir
        atlas %The registered atlas
        COVperc %Coefficient of variation in percent for the CBF
        view = 1 %Axial view = 1, Sagital = 3, Coronal = 2
    end
    
    methods (Access = private)
        
        function updateFigures(app)
            
            %Define colormap
            cmap = parula(256);
            cmap2 = [0 0 0; cmap];
            
            %Get current state of values
            Slice = round(app.SliceSlider.Value);
            
            %Set sliceData depending on view
            if(app.view == 1) %Axial
                sliceData = rot90(squeeze(app.ASLData(app.currentData,:,:,Slice)));
                sliceDataT1 = rot90(squeeze(app.T1Data(1,:,:,Slice)));
                sliceDataAtlas = rot90(squeeze(app.atlas(:,:,Slice)));
            elseif(app.view == 2) %Coronal
                sliceData = rot90(squeeze(app.ASLData(app.currentData,:,Slice,:)));
                sliceDataT1 = rot90(squeeze(app.T1Data(1,:,Slice,:)));
                sliceDataAtlas = rot90(squeeze(app.atlas(:,Slice,:)));
            elseif(app.view == 3) %Sagital
                sliceData = rot90(squeeze(app.ASLData(app.currentData,Slice,:,:)));
                sliceDataT1 = rot90(squeeze(app.T1Data(1,Slice,:,:)));
                sliceDataAtlas = rot90(squeeze(app.atlas(Slice,:,:)));
            end
            
            %Plot figure images
            cla(app.UIAxes3_9)
            imshow(sliceData,'parent',app.UIAxes3_9);
            colormap(app.UIAxes3_9,cmap2)
%             colorbar(app.UIAxes3_9);
            caxis(app.UIAxes3_9, [0 app.yLim])
            hold(app.UIAxes3_9,'on');
            contour(squeeze(sliceDataAtlas),'-m','parent',app.UIAxes3_9,'LineWidth',2);
            
            %Structural
            cla(app.UIAxes3_10)
            imshow(sliceDataT1,[app.yMinT1 app.yMaxT1],'parent',app.UIAxes3_10);
            hold(app.UIAxes3_10,'on');
            title(app.UIAxes3_10, app.DropDownDay.Value, 'Color','white');
            
            cla(app.UIAxes3_11)
            cMap3 = [0 0 0; ...
                0.0000    0.4470    0.7410; [0.0000    0.4470    0.7410]*0.75; ...
                0.8500    0.3250    0.0980; [0.8500    0.3250    0.0980]*0.75; ...
                0.9290    0.6940    0.1250; [0.9290    0.6940    0.1250]*0.75; ...
                0.4940    0.1840    0.5560; [0.4940    0.1840    0.5560]*0.75; ...
                0.4660    0.6740    0.1880; [0.4660    0.6740    0.1880]*0.75];
            
            imshow(sliceDataAtlas, [0 10],'parent',app.UIAxes3_11,'Colormap',cMap3);
            
            imoverlayAtlas(app);
        end
        
        function [mArea, sArea, COVperc] = calculateArea(app)
            
            %Calucalte mean/standard deviation in each ROI
            for jj = 1:length(app.ASLData(:,1,1,1))
                ACAL = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 1);
                ACAL(ACAL == 0) = NaN;
                mACAL(jj) = mean(mean(mean(ACAL,'omitnan'),'omitnan'),'omitnan');
                sACAL(jj) = std(std(std(ACAL,'omitnan'),'omitnan'),'omitnan');
                
                ACAR = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 2);
                ACAR(ACAR == 0) = NaN;
                mACAR(jj) = mean(mean(mean(ACAR,'omitnan'),'omitnan'),'omitnan');
                sACAR(jj) = std(std(std(ACAR,'omitnan'),'omitnan'),'omitnan');
                
                MCAL = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 3);
                MCAL(MCAL == 0) = NaN;
                mMCAL(jj) = mean(mean(mean(MCAL,'omitnan'),'omitnan'),'omitnan');
                sMCAL(jj) = std(std(std(MCAL,'omitnan'),'omitnan'),'omitnan');
                
                MCAR = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 4);
                MCAR(MCAR == 0) = NaN;
                mMCAR(jj) = mean(mean(mean(MCAR,'omitnan'),'omitnan'),'omitnan');
                sMCAR(jj) = std(std(std(MCAR,'omitnan'),'omitnan'),'omitnan');
                
                PCAL = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 5);
                PCAL(PCAL == 0) = NaN;
                mPCAL(jj) = mean(mean(mean(PCAL,'omitnan'),'omitnan'),'omitnan');
                sPCAL(jj) = std(std(std(PCAL,'omitnan'),'omitnan'),'omitnan');
                
                PCAR = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 6);
                PCAR(PCAR == 0) = NaN;
                mPCAR(jj) = mean(mean(mean(PCAR,'omitnan'),'omitnan'),'omitnan');
                sPCAR(jj) = std(std(std(PCAR,'omitnan'),'omitnan'),'omitnan');
                
                VBL = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 7);
                VBL(VBL == 0) = NaN;
                mVBL(jj) = mean(mean(mean(VBL,'omitnan'),'omitnan'),'omitnan');
                sVBL(jj) = std(std(std(VBL,'omitnan'),'omitnan'),'omitnan');
                
                VBR = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 8);
                VBR(VBR == 0) = NaN;
                mVBR(jj) = mean(mean(mean(VBR,'omitnan'),'omitnan'),'omitnan');
                sVBR(jj) = std(std(std(VBR,'omitnan'),'omitnan'),'omitnan');
                
                LVL = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 9);
                LVL(LVL == 0) = NaN;
                mLVL(jj) = mean(mean(mean(LVL,'omitnan'),'omitnan'),'omitnan');
                sLVL(jj) = std(std(std(LVL,'omitnan'),'omitnan'),'omitnan');
                
                LVR = squeeze(app.ASLData(jj,:,:,:)) .* (round(app.atlas) == 10);
                LVR(LVR == 0) = NaN;
                mLVR(jj) = mean(mean(mean(LVR,'omitnan'),'omitnan'),'omitnan');
                sLVR(jj) = std(std(std(LVR,'omitnan'),'omitnan'),'omitnan');
                
            end
            
            mArea = [mACAL; mACAR; mMCAL; mMCAR; mPCAL; mPCAR; mVBL; mVBR; mLVL; mLVR];
            sArea = [sACAL; sACAR; sMCAL; sMCAR; sPCAL; sPCAR; sVBL; sVBR; sLVL; sLVR];
            COVperc = sArea ./ mArea * 100;
            
        end
        
        function initializeData(app)
            
            %Set slider values
            app.SliceSlider.Limits = [1 length(app.ASLData(1,1,1,:))];
            app.SliceSlider.Value = round(length(app.ASLData(1,1,1,:))/2);
            step = round(length(app.ASLData(1,1,1,:))/4);
            app.SliceSlider.MajorTicks = [1 step 2*step 3*step 4*step];
            app.SliceSlider.MajorTickLabels = {'1' num2str(step) num2str(2*step) num2str(3*step) num2str(4*step)};
            
        end
        
        function downsampleData(app)
            %% Downsample ASL data
            %ASL proc data: Go from 256 slices to 40...
            %FIRST: Remove all added zero-slices!
            %Remember that this will not match structural images anymore!
            
            meanASLdata = squeeze(mean(app.ASLData,1)); %Mean over the measurements [176 256 256]
            count = 1;
            remInd = [];
            for jj=1:length(meanASLdata(1,1,:))
                if(sum(sum(squeeze(meanASLdata(:,:,jj)))) > 300)
                    remInd(count) = jj;
                    ASLnonZerodata(:,:,count) = meanASLdata(:,:,jj);
                    count = count+1;
                end
            end
            
            ASLnonZerodata = app.ASLData(:,:,:,remInd(1):remInd(end));
            
            %Second x*40=40x slices, then average to 40 slices.
            ASLProcData = zeros(length(app.ASLData(:,1,1,1)),length(app.ASLData(1,:,1,1)), length(app.ASLData(1,1,:,1)), 40);
            for kk=1:length(app.ASLData(:,1,1,1))
                slice40times = imresize3(squeeze(ASLnonZerodata(kk,:,:,:)), [length(ASLnonZerodata(1,:,1,1)) length(ASLnonZerodata(1,1,:,1)) length(ASLnonZerodata(1,1,1,:))*40], 'linear');
                for jj = 1:length(ASLnonZerodata(1,1,1,:)):length(slice40times(1,1,:))
                    ASLProcData(kk,:,:,(jj+length(ASLnonZerodata(1,1,1,:))-1)/length(ASLnonZerodata(1,1,1,:))) = mean(slice40times(:,:,jj:(jj+length(ASLnonZerodata(1,1,1,:))-1)),3);
                end
            end
            
            app.ASLData = ASLProcData;
            
            %Take out corresponding structural part of image
            firstSliceIdx = remInd(1); lastSliceIdx = remInd(end);
            app.T1Data = app.T1Data(:,:,:,firstSliceIdx:lastSliceIdx);
            
            %Second x*40=40x slices, then average to 40 slices.
            T1ProcData = zeros(length(app.T1Data(:,1,1,1)),length(app.T1Data(1,:,1,1)), length(app.T1Data(1,1,:,1)), 40);
            for kk=1:length(app.T1Data(:,1,1,1))
                slice40times = imresize3(squeeze(app.T1Data(kk,:,:,:)), [length(app.T1Data(1,:,1,1)) length(app.T1Data(1,1,:,1)) length(app.T1Data(1,1,1,:))*40], 'linear');
                for jj = 1:length(app.T1Data(1,1,1,:)):length(slice40times(1,1,:))
                    T1ProcData(kk,:,:,(jj+length(app.T1Data(1,1,1,:))-1)/length(app.T1Data(1,1,1,:))) = mean(slice40times(:,:,jj:(jj+length(app.T1Data(1,1,1,:))-1)),3);
                end
            end
            
            app.T1Data = T1ProcData;
            
            app.atlas = app.atlas(:,:,firstSliceIdx:lastSliceIdx);
            
            %Second x*40=40x slices, then average to 40 slices.
            AtlasProcData = zeros(length(app.atlas(:,1,1)), length(app.atlas(1,:,1)), 40);
            slice40times = imresize3(squeeze(app.atlas(:,:,:)), [length(app.atlas(:,1,1)) length(app.atlas(1,:,1)) length(app.atlas(1,1,:))*40], 'linear');
            for jj = 1:length(app.atlas(1,1,:)):length(slice40times(1,1,:))
                AtlasProcData(:,:,(jj+length(app.atlas(1,1,:))-1)/length(app.atlas(1,1,:))) = mean(slice40times(:,:,jj:(jj+length(app.atlas(1,1,:))-1)),3);
            end
            
            app.atlas = AtlasProcData;
            
        end
        
        function plotQuantification(app)
            
            colors =       [0.0000    0.4470    0.7410; ...
                0.8500    0.3250    0.0980; ...
                0.9290    0.6940    0.1250; ...
                0.4940    0.1840    0.5560; ...
                0.4660    0.6740    0.1880; ...
                0.3010    0.7450    0.9330; ...
                0.6350    0.0780    0.1840];
            
            
            [mArea, sArea, app.COVperc] = calculateArea(app);
            
            cla(app.UIAxes)
            cla(app.UIAxes_2) %Size mArea = [10 areas, X datasets]
            
            %Left size, ACA, MCA, PCA, VB, LV ?
            errorbar(1:length(mArea(1,:)),mArea(1,:),sArea(1,:),'Parent',app.UIAxes,'Color', colors(1,:)); hold(app.UIAxes,'on');
            plot(1:length(mArea(1,:)),mArea(1,:),'o','Parent',app.UIAxes,'Color', colors(1,:),'MarkerFaceColor',colors(1,:))
            
            errorbar(1:length(mArea(3,:)),mArea(3,:),sArea(3,:),'Parent',app.UIAxes,'Color', colors(2,:)); hold(app.UIAxes,'on');
            plot(1:length(mArea(3,:)),mArea(3,:),'o','Parent',app.UIAxes,'Color', colors(2,:),'MarkerFaceColor',colors(2,:))
            
            errorbar(1:length(mArea(5,:)),mArea(5,:),sArea(5,:),'Parent',app.UIAxes,'Color', colors(3,:)); hold(app.UIAxes,'on');
            plot(1:length(mArea(5,:)),mArea(5,:),'o','Parent',app.UIAxes,'Color', colors(3,:),'MarkerFaceColor',colors(3,:))
            
            errorbar(1:length(mArea(7,:)),mArea(7,:),sArea(7,:),'Parent',app.UIAxes,'Color', colors(4,:)); hold(app.UIAxes,'on');
            plot(1:length(mArea(7,:)),mArea(7,:),'o','Parent',app.UIAxes,'Color', colors(4,:),'MarkerFaceColor',colors(4,:))
            
            errorbar(1:length(mArea(9,:)),mArea(9,:),sArea(9,:),'Parent',app.UIAxes,'Color', colors(5,:)); hold(app.UIAxes,'on');
            plot(1:length(mArea(9,:)),mArea(9,:),'o','Parent',app.UIAxes,'Color', colors(5,:),'MarkerFaceColor',colors(5,:))
            
            %Right size, ACA, MCA, PCA, VB, LV ?
            errorbar(1:length(mArea(2,:)),mArea(2,:),sArea(2,:),'Parent',app.UIAxes_2,'Color', colors(1,:)); hold(app.UIAxes_2,'on');
            plot(1:length(mArea(2,:)),mArea(2,:),'o','Parent',app.UIAxes_2,'Color', colors(1,:),'MarkerFaceColor',colors(1,:))
            
            errorbar(1:length(mArea(4,:)),mArea(4,:),sArea(4,:),'Parent',app.UIAxes_2,'Color', colors(2,:)); hold(app.UIAxes_2,'on');
            plot(1:length(mArea(4,:)),mArea(4,:),'o','Parent',app.UIAxes_2,'Color', colors(2,:),'MarkerFaceColor',colors(2,:))
            
            errorbar(1:length(mArea(6,:)),mArea(6,:),sArea(6,:),'Parent',app.UIAxes_2,'Color', colors(3,:)); hold(app.UIAxes_2,'on');
            plot(1:length(mArea(6,:)),mArea(6,:),'o','Parent',app.UIAxes_2,'Color', colors(3,:),'MarkerFaceColor',colors(3,:))
            
            errorbar(1:length(mArea(8,:)),mArea(8,:),sArea(8,:),'Parent',app.UIAxes_2,'Color', colors(4,:)); hold(app.UIAxes_2,'on');
            plot(1:length(mArea(8,:)),mArea(8,:),'o','Parent',app.UIAxes_2,'Color', colors(4,:),'MarkerFaceColor',colors(4,:))
            
            errorbar(1:length(mArea(10,:)),mArea(10,:),sArea(10,:),'Parent',app.UIAxes_2,'Color', colors(5,:)); hold(app.UIAxes_2,'on');
            plot(1:length(mArea(10,:)),mArea(10,:),'o','Parent',app.UIAxes_2,'Color', colors(5,:),'MarkerFaceColor',colors(5,:))
            
            %Calucate limits
            ylimMin = floor(min(min(mArea))/10)*10;
            ylimMax = ceil(max(max(mArea))/10)*10;
            
            set(app.UIAxes,'xticklabel',{[]})
            xlim(app.UIAxes, [0 (length(app.DropDownDay.Items)+1)]);
            xticks(app.UIAxes, 1:length(app.DropDownDay.Items));
            xticklabels(app.UIAxes, app.DropDownDay.Items);
            xtickangle(app.UIAxes,90);
            xlabel(app.UIAxes,'');
            ylim(app.UIAxes,[ylimMin ylimMax])
            title(app.UIAxes,'Left','Color',[1 1 1])
            
            set(app.UIAxes_2,'xticklabel',{[]})
            xlim(app.UIAxes_2, [0 (length(app.DropDownDay.Items)+1)]);
            xticks(app.UIAxes_2, 1:length(app.DropDownDay.Items));
            xticklabels(app.UIAxes_2, app.DropDownDay.Items);
            xtickangle(app.UIAxes_2,90);
            xlabel(app.UIAxes_2,'');
            ylim(app.UIAxes_2,[ylimMin ylimMax])
            title(app.UIAxes_2,'Right','Color',[1 1 1])
            
            app.ACALabel.Visible = 1; app.ACALabel.Enable = 1; app.ACALabel.FontColor = colors(1,:);
            app.MCALabel.Visible = 1; app.MCALabel.Enable = 1;  app.MCALabel.FontColor = colors(2,:);
            app.PCALabel.Visible = 1; app.PCALabel.Enable = 1;  app.PCALabel.FontColor = colors(3,:);
            app.VBLabel.Visible = 1; app.VBLabel.Enable = 1;  app.VBLabel.FontColor = colors(4,:);
            app.LVLabel.Visible = 1; app.LVLabel.Enable = 1;  app.LVLabel.FontColor = colors(5,:);
            
        end
        
        function imoverlayAtlas(app)
            
            alpha = 0.4; % Default transparency value
            
            %Get current state of values
            Slice = round(app.SliceSlider.Value);
            
            %Set sliceData depending on view
            %Set sliceData depending on view
            if(app.view == 1) %Axial
                sliceDataT1 = rot90(squeeze(app.T1Data(1,:,:,Slice)));
                sliceDataAtlas = rot90(squeeze(app.atlas(:,:,Slice)));
            elseif(app.view == 2) %Coronal
                sliceDataT1 = rot90(squeeze(app.T1Data(1,:,Slice,:)));
                sliceDataAtlas = rot90(squeeze(app.atlas(:,Slice,:)));
            elseif(app.view == 3) %Sagital
                sliceDataT1 = rot90(squeeze(app.T1Data(1,Slice,:,:)));
                sliceDataAtlas = rot90(squeeze(app.atlas(Slice,:,:)));
            end
            
            % Display the back image
            climB = [min(sliceDataT1(:)), max(sliceDataT1(:))];
            B = repmat(mat2gray(double(sliceDataT1),double(climB)),[1,1,3]);
            imagesc(B,'parent',app.UIAxes3_10); hold(app.UIAxes3_10, 'on');

            cMap3 = [0 0 0; ...
                0.0000    0.4470    0.7410; [0.0000    0.4470    0.7410]*0.45; ...
                0.8500    0.3250    0.0980; [0.8500    0.3250    0.0980]*0.45; ...
                0.9290    0.6940    0.1250; [0.9290    0.6940    0.1250]*0.45; ...
                0.4940    0.1840    0.5560; [0.4940    0.1840    0.5560]*0.45; ...
                0.4660    0.6740    0.1880; [0.4660    0.6740    0.1880]*0.45];
            
            % Add the front image on top of the back image
            alphadata = alpha.*(sliceDataAtlas >= 0);
            hb = imshow(sliceDataAtlas, 'DisplayRange',[0 10],'parent',app.UIAxes3_10,'Colormap',cMap3);  
            set(hb,'AlphaData',alphadata);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            %Plot dataset
            updateFigures(app);
        end

        % Button pushed function: LoadCBFMapsButton
        function LoadCBFMapsButtonPushed(app, event)
            
            %Test for loading Registrated repeated ASL data
            
            %Clear if reload
            cla(app.UIAxes3_9); title(app.UIAxes3_9, '');
            cla(app.UIAxes3_10); title(app.UIAxes3_10, '');
            
            %Load the datafolder & get outputs from the data
            app.dataFolder = uigetdir();
            figure(app.UIFigure);
            
            d = dir([app.dataFolder '/OutputReg/ASL']);
            isub = [d(:).isdir];
            app.subFolders = {d(isub).name}';
            app.subFolders(ismember(app.subFolders,{'.','..'})) = [];
            
            %Get correct T1 data - BET and Reg to Day1 in nii format
            app.T1path = [app.dataFolder '/OutputReg/T1/T1_Ref_crop.nii.gz'];
            app.T1pathBet = [app.dataFolder '/OutputReg/T1/T1_Ref_bet.nii.gz'];
            
            app.ASLData = [];
            app.T1Data = [];
            app.days = {};
            for jj = 1:length(app.subFolders)
                ASLpath = [app.dataFolder '/OutputReg/ASL/' app.subFolders{jj}];
                app.ASLData(jj,:,:,:) = niftiread([ASLpath '/asl2struct.nii.gz']);
                app.days{jj} = app.subFolders{jj}(5:9);
            end
            
            %Calculate y-limits so they are the same for both BET and non BET
            tempT1 = double(niftiread(app.T1path));
            tempT1bet = double(niftiread(app.T1pathBet));
            
            tempMinT1 = min(tempT1(:));
            tempMinT1bet = min(tempT1bet(:));
            tempMaxT1 = max(tempT1(:));
            tempMaxT1bet = max(tempT1bet(:));
            
            app.yMinT1 = (tempMinT1bet+tempMinT1)/2;
            app.yMaxT1 = (tempMaxT1bet+tempMaxT1)/3;
            
            clear tempT1 tempT1bet
            
            if(app.noT1Bet)
                app.T1Data(1,:,:,:) = niftiread(app.T1path);
            else
                app.T1Data(1,:,:,:) = niftiread(app.T1pathBet);
            end
            
            %Downsamle to 40 slices
            %downsampleData(app);
            
            %Load atlas
            app.atlas = niftiread([app.dataFolder '/OutputReg/AtlasinRefspace.nii.gz']);
            
            %Make masks and remove the BET-issue, both from the atlas and
            %the ASL Map. Compute from the BET T1 image?
            betData = niftiread(app.T1pathBet);
            
            maskAtlas = imbinarize(app.atlas); 
            maskBET = imbinarize(betData);
            for jj = 1:length(maskAtlas(1,1,:))
                maskAtlas(:,:,jj) = imfill(squeeze(maskAtlas(:,:,jj)), 'holes');
                maskBET(:,:,jj) = imfill(squeeze(maskBET(:,:,jj)), 'holes');
            end
            
            app.atlas = app.atlas .* maskBET;
            for jj = 1:length(app.ASLData(:,1,1,1))
                app.ASLData(jj,:,:,:) = squeeze(app.ASLData(jj,:,:,:)) .* maskAtlas;
            end

            %Initialize those parts that is dependent on the view
            initializeData(app)
            
            %Set dropdown values
            app.DropDownDay.Items = app.days;
            app.DropDownDay.Value = app.days{1};
            app.currentData = 1; %First data in stack
            
            %Enable and make things visible
            app.SliceSlider.Visible = 1;
            app.SliceSliderLabel.Visible = 1;
            app.DropDownDay.Visible = 1;
            app.SliceSlider.Value = round(0.65*length(app.ASLData(1,1,1,:)));
            app.SliceSlider.Enable = 1;
            app.SliceSliderLabel.Enable = 1;
            app.DropDownDay.Enable = 1;
            app.SavematButton.Enable = 1;
            app.BETCheckBox.Enable = 1;
            app.SlicesCheckBox.Enable = 1;
            app.SliceEditField.Enable = 1;
            app.ViewDropDown.Enable = 1;
            app.ViewDropDownLabel.Enable = 1;
            
            %Plot first dataset and quantify areas
            plotQuantification(app)
            updateFigures(app);
            
        end

        % Value changed function: DropDownDay
        function DropDownDayValueChanged(app, event)
            value = app.DropDownDay.Value;
            for jj = 1:length(app.days)
                if(value == app.DropDownDay.Items{jj})
                    app.currentData = jj;
                end
            end
            updateFigures(app)
        end

        % Button pushed function: SavematButton
        function SavematButtonPushed(app, event)
            [mArea, sArea, COVperc] = calculateArea(app);
            ASLData = app.ASLData;
            atlas = app.atlas; 
            
            betData = niftiread(app.T1pathBet);
            T1bet = betData;
            
            [file,path] = uiputfile('*.mat');
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                disp(['User selected ',fullfile(path,file),' and then clicked Save.']);
                save(fullfile(path,file),'ASLData','mArea','sArea','COVperc','atlas','T1bet');
            end
            
            %Testrad efter load:
            % figure; subplot(321);image(squeeze(100*mask(1,:,:)));subplot(322);image(squeeze(100*mask(2,:,:)));subplot(323);image(squeeze(100*mask(3,:,:)));subplot(324);image(squeeze(100*mask(4,:,:)));subplot(325);image(squeeze(100*mask(5,:,:)));subplot(326);image(squeeze(100*mask(6,:,:)));
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            updateFigures(app);
            
        end

        % Value changed function: BETCheckBox
        function BETCheckBoxValueChanged(app, event)
            app.noT1Bet = ~app.BETCheckBox.Value;
            
            app.T1Data = [];
            
            if(app.noT1Bet)
                app.T1Data(1,:,:,:) = niftiread(app.T1path);
            else
                app.T1Data(1,:,:,:) = niftiread(app.T1pathBet);
            end
            
            if(app.to40Slices)
                %Re-read data
                app.ASLData = [];
                for jj = 1:length(app.subFolders)
                    ASLpath = [app.dataFolder '/OutputReg/ASL/' app.subFolders{jj}];
                    app.ASLData(jj,:,:,:) = niftiread([ASLpath '/asl2struct.nii.gz']);
                end
                downsampleData(app);
            end
            
            updateFigures(app);
            
        end

        % Value changed function: SlicesCheckBox
        function SlicesCheckBoxValueChanged(app, event)
            
            app.to40Slices = app.SlicesCheckBox.Value;
            
            %Re-read data
            app.ASLData = [];
            for jj = 1:length(app.subFolders)
                ASLpath = [app.dataFolder '/OutputReg/ASL/' app.subFolders{jj}];
                app.ASLData(jj,:,:,:) = niftiread([ASLpath '/asl2struct.nii.gz']);
            end
            
            app.T1Data = [];
            if(app.noT1Bet)
                app.T1Data(1,:,:,:) = niftiread(app.T1path);
            else
                app.T1Data(1,:,:,:) = niftiread(app.T1pathBet);
            end
            
            %Load atlas
            app.atlas = niftiread([app.dataFolder '/OutputReg/AtlasinRefspace.nii.gz']);
            
            if(app.to40Slices)
                downsampleData(app);
            end
            
            %Set slider values
            app.SliceSlider.Limits = [1 length(app.ASLData(1,1,1,:))];
            app.SliceSlider.Value = round(length(app.ASLData(1,1,1,:))/2);
            step = round(length(app.ASLData(1,1,1,:))/4);
            app.SliceSlider.MajorTicks = [1 step 2*step 3*step 4*step];
            app.SliceSlider.MajorTickLabels = {'1' num2str(step) num2str(2*step) num2str(3*step) num2str(4*step)};
            app.SliceSlider.Value = round(0.65*length(app.ASLData(1,1,1,:)));
            
            updateFigures(app);
        end

        % Value changed function: SliceEditField
        function SliceEditFieldValueChanged(app, event)
            value = app.SliceEditField.Value;
            if(value > app.SliceSlider.Limits(2))
                app.SliceSlider.Value = app.SliceSlider.Limits(2);
            elseif(value < app.SliceSlider.Limits(1))
                app.SliceSlider.Value = app.SliceSlider.Limits(1);
            else
                app.SliceSlider.Value = value;
            end
            
            updateFigures(app);
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            for jj = 1:3
                if(strcmp(value,app.ViewDropDown.Items{jj}))
                    app.view = jj;
                end
            end
            initializeData(app);
            updateFigures(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3_9 = uiaxes(app.UIFigure);
            app.UIAxes3_9.XTick = [];
            app.UIAxes3_9.XTickLabel = '';
            app.UIAxes3_9.YTick = [];
            app.UIAxes3_9.YTickLabel = '';
            app.UIAxes3_10 = uiaxes(app.UIFigure);
            app.UIAxes3_10.XTick = [];
            app.UIAxes3_10.XTickLabel = '';
            app.UIAxes3_10.YTick = [];
            app.UIAxes3_10.YTickLabel = '';
            app.UIAxes3_11 = uiaxes(app.UIFigure);
            app.UIAxes3_11.XTick = [];
            app.UIAxes3_11.XTickLabel = '';
            app.UIAxes3_11.YTick = [];
            app.UIAxes3_11.YTickLabel = '';
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Enable = 'off';
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.Limits = [1 40];
            app.SliceSlider.MajorTicks = [5 10 15 20 25 30 35 40];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [1 2 3 4 6 7 8 9 11 12 13 14 16 17 18 19 21 22 23 24 26 27 28 29 31 32 33 34 36 37 38 39];
            app.SliceSlider.Enable = 'off';
            app.SliceSlider.Value = 20;
            app.DropDownDay = uidropdown(app.UIFigure);
            app.DropDownDay.Items = {'Day01'};
            app.DropDownDay.ValueChangedFcn = createCallbackFcn(app, @DropDownDayValueChanged, true);
            app.DropDownDay.Enable = 'off';
            app.DropDownDay.Value = 'Day01';
            app.LoadCBFMapsButton = uibutton(app.UIFigure, 'push');
            app.LoadCBFMapsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCBFMapsButtonPushed, true);
            app.LoadCBFMapsButton.Text = 'Load CBF Maps';
            app.QuantifyPanel = uipanel(app.UIFigure);
            app.QuantifyPanel.Title = 'Quantify';
            app.UIAxes = uiaxes(app.QuantifyPanel);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'Time Point')
            ylabel(app.UIAxes, 'CBF [ml/100g/min]')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2 = uiaxes(app.QuantifyPanel);
            title(app.UIAxes_2, {''; ''})
            xlabel(app.UIAxes_2, 'Time Point')
            ylabel(app.UIAxes_2, 'CBF [ml/100g/min]')
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ACALabel = uilabel(app.QuantifyPanel);
            app.ACALabel.Enable = 'off';
            app.ACALabel.Visible = 'off';
            app.ACALabel.Text = 'ACA';
            app.MCALabel = uilabel(app.QuantifyPanel);
            app.MCALabel.Enable = 'off';
            app.MCALabel.Visible = 'off';
            app.MCALabel.Text = 'MCA';
            app.PCALabel = uilabel(app.QuantifyPanel);
            app.PCALabel.Enable = 'off';
            app.PCALabel.Visible = 'off';
            app.PCALabel.Text = 'PCA';
            app.VBLabel = uilabel(app.QuantifyPanel);
            app.VBLabel.Enable = 'off';
            app.VBLabel.Visible = 'off';
            app.VBLabel.Text = 'VB';
            app.LVLabel = uilabel(app.QuantifyPanel);
            app.LVLabel.Enable = 'off';
            app.LVLabel.Visible = 'off';
            app.LVLabel.Text = 'LV';
            app.SavematButton = uibutton(app.UIFigure, 'push');
            app.SavematButton.ButtonPushedFcn = createCallbackFcn(app, @SavematButtonPushed, true);
            app.SavematButton.Enable = 'off';
            app.SavematButton.Text = 'Save .mat';
            app.BETCheckBox = uicheckbox(app.UIFigure);
            app.BETCheckBox.ValueChangedFcn = createCallbackFcn(app, @BETCheckBoxValueChanged, true);
            app.BETCheckBox.Enable = 'off';
            app.BETCheckBox.Text = 'BET';
            app.SlicesCheckBox = uicheckbox(app.UIFigure);
            app.SlicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SlicesCheckBoxValueChanged, true);
            app.SlicesCheckBox.Enable = 'off';
            app.SlicesCheckBox.Text = '40 Slices';
            app.SliceEditFieldLabel = uilabel(app.UIFigure);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SliceEditField = uieditfield(app.UIFigure, 'numeric');
            app.SliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceEditFieldValueChanged, true);
            app.SliceEditField.Enable = 'off';
            app.ViewDropDownLabel = uilabel(app.UIFigure);
            app.ViewDropDownLabel.Enable = 'off';
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.UIFigure);
            app.ViewDropDown.Items = {'Axial', 'Coronal', 'Sagital'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Enable = 'off';
            app.ViewDropDown.Value = 'Axial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_VisAndQuant_ASLResults

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end